classdef NSMode4GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        TimeDisplay             matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        USBField                matlab.ui.control.EditField
        PortEditFieldLabel      matlab.ui.control.Label
        USBStatus               matlab.ui.control.Lamp
        USBConnectionLampLabel  matlab.ui.control.Label
        ConnectUSB              matlab.ui.control.Button
        CollectStatus           matlab.ui.control.Lamp
        CollectLampLabel        matlab.ui.control.Label
        TimeInput               matlab.ui.control.NumericEditField
        InputsEditFieldLabel    matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CounterValue = 0; % number of "samples"
        CounterRunning = false;  % collect mode
        device = "COM#"; 
        dataOutput = zeros(50000,20,"uint8"); % max 50000 samples
        currentTime % current time count (000 s) of collection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CollectStatus.Color = [1, 0, 0]; % RGB
            app.USBStatus.Color = [1, 0, 0];
            app.StartButton.Enable = 0;
        end

        % Button pushed function: ConnectUSB
        function ConnectUSBButtonPushed(app, event)
            serialPort = app.USBField.Value;
            app.device = serialport(serialPort,9600); % setup USB connection
            
            app.USBStatus.Color = [0, 1, 0];
            app.StartButton.Enable = 1;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.CollectStatus.Color = [0, 1, 0];
            app.SaveButton.Enable = 0;
            app.StartButton.Enable = 0;
            app.CounterValue = 0;
            input = app.TimeInput.Value;
            command = "@M4" + num2str(input, '%03d');
            write(app.device,command,"string");
            if ~app.CounterRunning
                app.CounterRunning = true;
                while app.CounterRunning
                    if (app.currentTime >= input)
                        break;
                    end
                    app.CounterValue = app.CounterValue + 1;
                    app.currentTime = app.CounterValue/50;
                    app.TimeDisplay.Text = string(app.currentTime);
                    app.dataOutput(app.CounterValue,1:20) = read(app.device,20,"uint8");

                end
                app.StartButton.Enable = 1;
                app.CollectStatus.Color = [1, 0, 0];
                app.SaveButton.Enable = 1;

            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.CounterRunning
                app.CounterRunning = false;
            end

            app.SaveButton.Enable = 0;
            app.CollectStatus.Color = [1, 0, 0];

            dataRaw = app.dataOutput;
            dataRaw( ~any(dataRaw,2), : ) = []; % remove 0 zero rows
            save('data.mat',"dataRaw");
            app.dataOutput = zeros(50000,20,"uint8"); % reset data
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InputsEditFieldLabel = uilabel(app.UIFigure);
            app.InputsEditFieldLabel.Text = 'Input (s)';
            app.TimeInput = uieditfield(app.UIFigure, 'numeric');
            app.TimeInput.Limits = [0 999];
            app.TimeInput.RoundFractionalValues = 'on';
            app.TimeInput.ValueDisplayFormat = '%.0f';
            app.CollectLampLabel = uilabel(app.UIFigure);
            app.CollectLampLabel.Text = 'Collect';
            app.CollectStatus = uilamp(app.UIFigure);
            app.ConnectUSB = uibutton(app.UIFigure, 'push');
            app.ConnectUSB.ButtonPushedFcn = createCallbackFcn(app, @ConnectUSBButtonPushed, true);
            app.ConnectUSB.Text = 'Connect USB';
            app.USBConnectionLampLabel = uilabel(app.UIFigure);
            app.USBConnectionLampLabel.Text = 'USB Connection';
            app.USBStatus = uilamp(app.UIFigure);
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.USBField = uieditfield(app.UIFigure, 'text');
            app.USBField.Placeholder = 'COM#';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.TimeDisplay = uilabel(app.UIFigure);
            app.TimeDisplay.Text = '0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Time (s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSMode4GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end