classdef NSRealTimeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        USBField                matlab.ui.control.EditField
        PortEditFieldLabel      matlab.ui.control.Label
        CollectStatus           matlab.ui.control.Lamp
        CollectionLamp_2Label   matlab.ui.control.Label
        ConnectUSB              matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        SamplesLabel            matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        USBStatus               matlab.ui.control.Lamp
        USBConnectionLampLabel  matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CounterDisplay matlab.ui.control.Label
        CollectionLight matlab.ui.control.Lamp
        CounterValue = 0; % number of "samples"
        CounterRunning = false;  % collect mode
        device = 1; % default value for COM port
        dataOutput = zeros(50000,20,"uint8"); % max 50000 samples
        PlotHandle;
    end
    methods (Access = private)
        function data = dataCollect(app, device)
            data = read(device,20,"uint8");
        end
        function parsedDataOut = NSparse(app, data, columns)
            % ASCII col vector
            binVal = '';
            
            for ii = 1:length(columns)
                binVal = [binVal, dec2bin(data(columns(ii)), 8)];
            end
            parsedDataOut = bin2dec(binVal);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % startup colors
            app.CollectStatus.Color = [1, 0, 0];
            app.USBStatus.Color = [1, 0, 0];
            % button turned off
            app.StopButton.Enable = 0;
            app.StartButton.Enable = 0;
            app.CounterDisplay = app.Label; 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.CollectStatus.Color = [0, 1, 0];
            app.SaveButton.Enable = 0;
            app.StopButton.Enable = 1;
            app.StartButton.Enable = 0;
            app.CounterValue = 0; % reset sample #
            
            % Set up the external real-time plot
            plotFigure = figure('Name', 'Photodiode Output');
            plotAxes = axes(plotFigure);
            app.PlotHandle = animatedline(plotAxes, 'Color', 'b', 'LineWidth', 1);
            xlabel(plotAxes, 'Time (s)');
            ylabel(plotAxes, 'Photodiode Output (V)');
            title(plotAxes,'Real Time Photodiode Output')
            grid(plotAxes, 'on');
            plotAxes.XLimMode = 'auto';
            plotAxes.YLimMode = 'auto';

           
            firstSample = 1; tRef = 0;
            command = "@M2"; % Teensy start cmd
            write(app.device,command,"string"); 
            if ~app.CounterRunning
                app.CounterRunning = true;
                while app.CounterRunning
                    app.CounterValue = app.CounterValue + 1;
                    app.CounterDisplay.Text = string(app.CounterValue);
                    app.dataOutput(app.CounterValue,1:20) = dataCollect(app,app.device);
                    data = app.dataOutput(app.CounterValue,1:20);
                    % Extract time reference
                    if (firstSample)
                        timeCol = 2:5;
                        tRef = NSparse(app,data(1,:),flip(timeCol));
                        tRef = tRef/1000; % ms -> s
                        firstSample = 0;
                    end
                    % Extract pdOut from 'data'
                    pdCol = 6:7;
                    pdOut = NSparse(app,data(1,:),flip(pdCol));
                    pdOut = 1.25*pdOut/(2^16-1); % currently voltage
                    % Extract time from 'data'
                    timeCol = 2:5;
                    timeOut = NSparse(app,data(1,:),flip(timeCol));
                    timeOut = timeOut/1000 - tRef; % ms -> s
                    % Update the real-time plot
                    addpoints(app.PlotHandle, timeOut, pdOut);
                    %app.PlotAxes.XLim = [t - 30, t]; % Adjust the time window as needed
                    drawnow;
            
                    % Add a pause to avoid overloading the CPU
                    pause(0.005); 
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.CollectStatus.Color = [1, 0, 0];
            command = "@SS"; % Teensy stop cmd
            write(app.device,command,"string");
            if app.CounterRunning
                app.CounterRunning = false;
            end
            app.SaveButton.Enable = 1;
            app.StartButton.Enable = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop the counter before closing the app
            if app.CounterRunning
                StopButtonPushed(app);
                pause(.1);
            end
            delete(app.UIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.CounterRunning
                app.CounterRunning = false;
            end

            app.StopButton.Enable = 0;
            app.SaveButton.Enable = 0;
            app.CollectStatus.Color = [1, 0, 0];

            dataRaw = app.dataOutput;
            dataRaw( ~any(dataRaw,2), : ) = []; % remove 0 zero rows
            save('data.mat',"dataRaw"); 
            app.dataOutput = zeros(50000,20,"uint8"); % reset data
        end

        % Button pushed function: ConnectUSB
        function ConnectUSBPushed(app, event)
            serialPort = app.USBField.Value;
            app.device = serialport(serialPort,9600); % setup USB connection
            
            app.USBStatus.Color = [0, 1, 0];
            app.StartButton.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.USBConnectionLampLabel = uilabel(app.UIFigure);
            app.USBConnectionLampLabel.Text = 'USB Connection';
            app.USBStatus = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.SamplesLabel = uilabel(app.UIFigure);
            app.SamplesLabel.Text = '# Samples';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ConnectUSB = uibutton(app.UIFigure, 'push');
            app.ConnectUSB.ButtonPushedFcn = createCallbackFcn(app, @ConnectUSBPushed, true);
            app.ConnectUSB.Text = 'Connect USB';
            app.CollectionLamp_2Label = uilabel(app.UIFigure);
            app.CollectionLamp_2Label.Text = 'Collection';
            app.CollectStatus = uilamp(app.UIFigure);
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.USBField = uieditfield(app.UIFigure, 'text');
            app.USBField.Placeholder = 'COM#';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSRealTimeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end