classdef NSMode2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SamplesLabel         matlab.ui.control.Label
        Label                matlab.ui.control.Label
        CollectionLamp       matlab.ui.control.Lamp
        CollectionLampLabel  matlab.ui.control.Label
        StopButton           matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CounterValue = 0;
        CounterRunning = false;
        CounterDisplay matlab.ui.control.Label
        CollectionLight matlab.ui.control.Lamp
        device = 1;
        dataOutput;
    end
    methods (Access = private)
        function data = dataCollect(app, device)
            data = ones(20,1,"uint8");
            %data = read(device,20,"uint8");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CollectionLamp.Color = [1, 0, 0];
            app.CounterDisplay = app.Label; 
            app.dataOutput = zeros(3000,20,"uint8"); % max 60000 samples
            %app.device = serialport("COM#",9600); % setup USB connection
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.CollectionLamp.Color = [0, 1, 0];
            command = "@M2";
            fprintf(command);
            %write(app.device,command,"string");
            if ~app.CounterRunning
                app.CounterRunning = true;
                while app.CounterRunning
                    app.CounterValue = app.CounterValue + 1;
                    app.CounterDisplay.Text = string(app.CounterValue);
                    app.dataOutput(app.CounterValue,1:20) = dataCollect(app,app.device);
                    pause(0.001);
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.CollectionLamp.Color = [1, 0, 0];
            command = "@SS";
            fprintf(command);
            %write(app.device,command,"string");
            if app.CounterRunning
                app.CounterRunning = false;
            end
            dataUseful = app.dataOutput;
            dataUseful( ~any(dataUseful,2), : ) = [];
            dataUseful;
            save('data.mat',"dataUseful");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop the counter before closing the app
            if app.CounterRunning
                StopButtonPushed(app);
                pause(1);
            end
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CollectionLampLabel = uilabel(app.UIFigure);
            app.CollectionLampLabel.Text = 'Collection';
            app.CollectionLamp = uilamp(app.UIFigure);
            app.CollectionLamp.Tag = 'Light';
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = 'Label';
            app.Label.Text = '0';
            app.SamplesLabel = uilabel(app.UIFigure);
            app.SamplesLabel.Text = '# Samples';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSMode2GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end