classdef DataFunctionsOscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DataSavedLamp             matlab.ui.control.Lamp
        DataSavedLampLabel        matlab.ui.control.Label
        DataConvertedLamp         matlab.ui.control.Lamp
        DataConvertedLampLabel    matlab.ui.control.Label
        DataParsedLamp            matlab.ui.control.Lamp
        DataParsedLampLabel       matlab.ui.control.Label
        DataLoadedLamp            matlab.ui.control.Lamp
        DataLoadedLampLabel       matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        ConvertButton             matlab.ui.control.Button
        SelectGraphButtonGroup    matlab.ui.container.ButtonGroup
        PhotodiodevsOscopeButton  matlab.ui.control.ToggleButton
        TimeButton                matlab.ui.control.ToggleButton
        GraphButton               matlab.ui.control.Button
        ParseButton               matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataRaw = zeros(50000,20,"uint8"); % max 50000 samples / 16 mins
        dataParse = zeros(50000,11,'uint32');
        dataConvert = zeros(50000,11); % 
        dataOscope = zeros(50000,1);
    end
    
    methods (Access = private)
    
        function parsedDataOut = NSparse(app, data, columns)
            % ASCII col vector
            binVal = '';
            
            for ii = 1:length(columns)
                binVal = [binVal, dec2bin(data(columns(ii)), 8)];
            end
            parsedDataOut = bin2dec(binVal);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ParseButton.Enable = 0;
            app.ConvertButton.Enable = 0;
            app.SaveButton.Enable = 0;
            app.GraphButton.Enable = 0;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load("data.mat");
            app.dataRaw = dataRaw;
            app.dataRaw( ~any(app.dataRaw,2), : ) = []; % remove 0 zero rows
            app.dataOscope = dataOscope;
            fprintf("Done Loading \n");
            app.DataLoadedLamp.Color = [0,1,0];
            app.ParseButton.Enable = 1;
            app.LoadButton.Enable = 0;
            app.GraphButton.Enable = 1;
        end

        % Button pushed function: ParseButton
        function ParseButtonPushed(app, event)
            rawData = app.dataRaw;
            timeSteps = height(rawData);
            startCol = 1;
            stopCol = 20;
            startOut = zeros(timeSteps, 1);
            stopOut = zeros(timeSteps, 1);
            timeCol = 2:5;
            timeOut = zeros(timeSteps, 1);
            pdCol = 6:7;
            pdOut = timeOut;
            thresholdCol = 8;
            thresholdOut = timeOut;
            sunCol = 9;
            sunOut = timeOut;
            pdThermCol = 10:11;
            pdThermOut = timeOut;
            analogThermCol = 12:13;
            analogThermOut = timeOut;
            digitalThermCol = 14:15;
            digitalThermOut = timeOut;
            analogCurrentCol = 16:17;
            analogCurrentOut = timeOut;
            digitalCurrentCol = 18:19;
            digitalCurrentOut = timeOut;
            
            % Parse!
            for i = 1:timeSteps
                startOut(i) = NSparse(app,rawData(i, :), startCol);
                stopOut(i) = NSparse(app,rawData(i, :), stopCol);
                timeOut(i) =  NSparse(app,rawData(i, :), flip(timeCol));
                pdOut(i) =  NSparse(app,rawData(i, :), flip(pdCol));
                thresholdOut(i) =  NSparse(app,rawData(i, :), thresholdCol);
                sunOut(i) =  NSparse(app,rawData(i, :), sunCol);
                pdThermOut(i) =  NSparse(app,rawData(i, :), flip(pdThermCol));
                analogThermOut(i) =  NSparse(app,rawData(i, :), flip(analogThermCol));
                digitalThermOut(i) =  NSparse(app,rawData(i, :), flip(digitalThermCol));
                analogCurrentOut(i) =  NSparse(app,rawData(i, :), flip(analogCurrentCol));
                digitalCurrentOut(i) =  NSparse(app,rawData(i, :), flip(digitalCurrentCol));
            end

            app.dataParse = [startOut,timeOut,pdOut,thresholdOut,sunOut,pdThermOut,analogThermOut,...
                digitalThermOut,analogCurrentOut,digitalCurrentOut,stopOut];
            fprintf("Done Parsing \n");
            app.DataParsedLamp.Color = [0,1,0];
            app.ConvertButton.Enable = 1;
            app.ParseButton.Enable = 0;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            data = app.dataParse;
            % Data Processing
            % Start Byte
            startOut = data(:,1);
            % Stop Byte
            stopOut = data(:,11);
            % Time
            timeOut = data(:,2);
            timeOut = timeOut - timeOut(1);
            %timeOut = timeOut(1:end-1,:);
            timeOut = timeOut/1000; % ms -> s
            % PD
            pdOut = data(:,3);
            pdOut = 1.25*pdOut/2^16; % currently voltage
            % Threshold, sun positions are just flags
            thresholdOut = data(:,4);
            sunOut = data(:,5);
            % Therms and Currents need conversion tables 
            pdThermOut = data(:,6);
            analogThermOut = data(:,7);
            digitalThermOut = data(:,8);
            analogCurrentOut = data(:,9);
            digitalCurrentOut = data(:,10);
            app.dataConvert = [startOut,timeOut,pdOut,thresholdOut,sunOut,pdThermOut,analogThermOut,...
                digitalThermOut,analogCurrentOut,digitalCurrentOut,stopOut];
            fprintf("Done Converting \n")
            app.DataConvertedLamp.Color = [0,1,0];
            app.SaveButton.Enable = 1;
            app.ConvertButton.Enable = 0;
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            data = app.dataConvert;
            timeOut = data(:,2);
            pdOut = data(:,3);
            scopeOut = app.dataOscope;
            if (app.TimeButton.Value)
                samples = 1:length(timeOut);
                % Create a figure
                f = uifigure('Position', [100 100 600 400]);
                
                % Add an axes to the figure
                ax1 = uiaxes(f, 'Position', [50 50 500 300]);
                
                % Plot the data on the axes
                plot(ax1, timeOut, samples);
                
                % Add x and y axis labels
                title(ax1, "Time vs Sample Number");
                xlabel(ax1, 'Time (s)');
                ylabel(ax1, 'Sample #');
                
                % Adjust the size of the plot
                ax1.XAxis.FontSize = 12;
                ax1.YAxis.FontSize = 12;
                ax1.XAxis.Label.FontSize = 14;
                ax1.YAxis.Label.FontSize = 14;
                ax1.Title.FontSize = 16;
            end

            if (app.PhotodiodevsOscopeButton.Value)

                % Create a figure
                f = uifigure('Position', [100 100 600 400]);
                
                % Add an axes to the figure
                ax1 = uiaxes(f, 'Position', [50 50 500 300]);
                hold(ax1,'on');
                % Plot the data on the axes
                plot(ax1, timeOut, pdOut);
                plot(ax1, timeOut, scopeOut);

                % Add x and y axis labels
                title(ax1, "Voltages vs Time");
                xlabel(ax1, 'Time (s)');
                ylabel(ax1, 'Voltage (V)');
                
                % legend
                legend(ax1, 'ADC Voltage', 'oScope Voltage');
                % Adjust the size of the plot
                ax1.XAxis.FontSize = 12;
                ax1.YAxis.FontSize = 12;
                ax1.XAxis.Label.FontSize = 14;
                ax1.YAxis.Label.FontSize = 14;
                ax1.Title.FontSize = 16;

                hold(ax1,'off');
            end   

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dataRaw = app.dataRaw;
            dataParse = app.dataParse;
            dataConvert = app.dataConvert;
            dataOscope = app.dataOscope;
            save('data.mat','dataRaw','dataParse',"dataConvert","dataOscope");
            fprintf("Done Saving \n")
            app.DataSavedLamp.Color = [0,1,0];
            app.SaveButton.Enable = 0;
            app.LoadButton.Enable = 1;
            app.GraphButton.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ParseButton = uibutton(app.UIFigure, 'push');
            app.ParseButton.ButtonPushedFcn = createCallbackFcn(app, @ParseButtonPushed, true);
            app.ParseButton.Text = 'Parse';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.SelectGraphButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectGraphButtonGroup.Title = 'Select Graph';
            app.TimeButton = uitogglebutton(app.SelectGraphButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.PhotodiodevsOscopeButton = uitogglebutton(app.SelectGraphButtonGroup);
            app.PhotodiodevsOscopeButton.Text = {'Photodiode'; 'vs Oscope'};
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DataLoadedLampLabel = uilabel(app.UIFigure);
            app.DataLoadedLampLabel.Text = 'Data Loaded';
            app.DataLoadedLamp = uilamp(app.UIFigure);
            app.DataParsedLampLabel = uilabel(app.UIFigure);
            app.DataParsedLampLabel.Text = 'Data Parsed';
            app.DataParsedLamp = uilamp(app.UIFigure);
            app.DataConvertedLampLabel = uilabel(app.UIFigure);
            app.DataConvertedLampLabel.Text = 'Data Converted';
            app.DataConvertedLamp = uilamp(app.UIFigure);
            app.DataSavedLampLabel = uilabel(app.UIFigure);
            app.DataSavedLampLabel.Text = 'Data Saved';
            app.DataSavedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataFunctionsOscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end